classdef MissionPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        XpointEditFieldLabel           matlab.ui.control.Label
        XpointEditField                matlab.ui.control.NumericEditField
        AddPointButton                 matlab.ui.control.Button
        YpointEditFieldLabel           matlab.ui.control.Label
        YpointEditField                matlab.ui.control.NumericEditField
        ZpointEditFieldLabel           matlab.ui.control.Label
        ZpointEditField                matlab.ui.control.NumericEditField
        YawAngledegEditFieldLabel      matlab.ui.control.Label
        YawAngledegEditField           matlab.ui.control.NumericEditField
        PlotTrajectoryButton           matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        SelectYourTrajectoryModeLabel  matlab.ui.control.Label
        VehicleTrajectoryLabel         matlab.ui.control.Label
        XtFunctionEditFieldLabel       matlab.ui.control.Label
        XtFunctionEditField            matlab.ui.control.EditField
        YtFunctionEditFieldLabel       matlab.ui.control.Label
        YtFunctionEditField            matlab.ui.control.EditField
        ZtFunctionEditFieldLabel       matlab.ui.control.Label
        ZtFunctionEditField            matlab.ui.control.EditField
        RestartButton                  matlab.ui.control.Button
        TimegivenbetweentwopositionpointsLabel  matlab.ui.control.Label
        tEditField                     matlab.ui.control.NumericEditField
        proportionaltodistanceinmetersLabel  matlab.ui.control.Label
        SubmitButton                   matlab.ui.control.Button
        XlimminmaxEditFieldLabel       matlab.ui.control.Label
        XlimminmaxEditField            matlab.ui.control.EditField
        YlimminmaxEditFieldLabel       matlab.ui.control.Label
        YlimminmaxEditField            matlab.ui.control.EditField
        ZlimmaxEditFieldLabel          matlab.ui.control.Label
        ZlimmaxEditField               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        missionPlanX = [];
        missionPlanY = [];
        missionPlanZ = [];
        missionPlanYaw = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.XtFunctionEditFieldLabel.Visible = 'off';
            app.YtFunctionEditFieldLabel.Visible = 'off';
            app.ZtFunctionEditFieldLabel.Visible = 'off';
            app.XtFunctionEditField.Visible = 'off';
            app.YtFunctionEditField.Visible = 'off';
            app.ZtFunctionEditField.Visible = 'off';
            app.PlotTrajectoryButton.Visible = 'off';
            app.XlimminmaxEditField.Visible = 'off';
            app.XlimminmaxEditFieldLabel.Visible = 'off';
            app.YlimminmaxEditField.Visible = 'off';
            app.YlimminmaxEditFieldLabel.Visible = 'off';
            app.ZlimmaxEditField.Visible = 'off';
            app.ZlimmaxEditFieldLabel.Visible = 'off';
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
          x = app.XpointEditField.Value;
          y = app.YpointEditField.Value;
          z = app.ZpointEditField.Value;
          yaw = app.YawAngledegEditField.Value;
      
          % Add the point to the mission plan
          app.missionPlanX = [app.missionPlanX, x];
          app.missionPlanY = [app.missionPlanY, y];
          app.missionPlanZ = [app.missionPlanZ, z];
          app.missionPlanYaw = [app.missionPlanYaw, yaw];
      
          % Plot the complete mission plan
          plot3(app.UIAxes, app.missionPlanX, app.missionPlanY, app.missionPlanZ, 'ro-');
          drawnow;
      
          % Save data to workspace for use in Simulink
          assignin('base', 'missionPlanX', app.missionPlanX);
          assignin('base', 'missionPlanY', app.missionPlanY);
          assignin('base', 'missionPlanZ', app.missionPlanZ);
          assignin('base', 'missionPlanYaw', app.missionPlanYaw);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
        switchValue = app.Switch.Value;
        % Default visibility
      
            % Update component visibility based on the switch value
            if strcmp(switchValue, 'Points')
                % Show the components related to trajectory points
                app.XpointEditFieldLabel.Visible = 'on';
                app.XpointEditField.Visible = 'on';
                app.YpointEditFieldLabel.Visible = 'on';
                app.YpointEditField.Visible = 'on';
                app.ZpointEditFieldLabel.Visible = 'on';
                app.ZpointEditField.Visible = 'on';
                app.YawAngledegEditFieldLabel.Visible = 'on';
                app.YawAngledegEditField.Visible = 'on';
                app.AddPointButton.Visible = 'on';
                app.XlimminmaxEditField.Visible = 'off';
                app.XlimminmaxEditFieldLabel.Visible = 'off';
                app.YlimminmaxEditField.Visible = 'off';
                app.YlimminmaxEditFieldLabel.Visible = 'off';
                app.ZlimmaxEditField.Visible = 'off';
                app.ZlimmaxEditFieldLabel.Visible = 'off';
                app.XtFunctionEditFieldLabel.Visible = 'off';
                app.YtFunctionEditFieldLabel.Visible = 'off';
                app.ZtFunctionEditFieldLabel.Visible = 'off';
                app.XtFunctionEditField.Visible = 'off';
                app.YtFunctionEditField.Visible = 'off';
                app.ZtFunctionEditField.Visible = 'off';
                app.PlotTrajectoryButton.Visible = 'off';
            elseif strcmp(switchValue, 'Function')
                % Hide the components related to trajectory points
                app.XpointEditFieldLabel.Visible = 'off';
                app.XpointEditField.Visible = 'off';
                app.YpointEditFieldLabel.Visible = 'off';
                app.YpointEditField.Visible = 'off';
                app.ZpointEditFieldLabel.Visible = 'off';
                app.ZpointEditField.Visible = 'off';
                app.YawAngledegEditFieldLabel.Visible = 'off';
                app.YawAngledegEditField.Visible = 'off';
                app.AddPointButton.Visible = 'off';

                % Show the components related to the trajectory function
                app.XlimminmaxEditField.Visible = 'on';
                app.XlimminmaxEditFieldLabel.Visible = 'on';
                app.YlimminmaxEditField.Visible = 'on';
                app.YlimminmaxEditFieldLabel.Visible = 'on';
                app.ZlimmaxEditField.Visible = 'on';
                app.ZlimmaxEditFieldLabel.Visible = 'on';
                app.XtFunctionEditFieldLabel.Visible = 'on';
                app.YtFunctionEditFieldLabel.Visible = 'on';
                app.ZtFunctionEditFieldLabel.Visible = 'on';
                app.XtFunctionEditField.Visible = 'on';
                app.YtFunctionEditField.Visible = 'on';
                app.ZtFunctionEditField.Visible = 'on';
                app.PlotTrajectoryButton.Visible = 'on';
            end   
        end

        % Button pushed function: PlotTrajectoryButton
        function PlotTrajectoryButtonPushed(app, event)
            % Define the time range
            t = linspace(-100, 100, 10000); % Adjust the range and number of points as needed
            
            % Get the X(t), Y(t), and Z(t) functions from the edit fields
            xFunction = app.XtFunctionEditField.Value;
            yFunction = app.YtFunctionEditField.Value;
            zFunction = app.ZtFunctionEditField.Value;
            
            % Detect if the functions are constants or involve 't'
            isConstantX = ~contains(xFunction, 't');
            isConstantY = ~contains(yFunction, 't');
            isConstantZ = ~contains(zFunction, 't');
            
            if isConstantX
                x = str2double(xFunction) * ones(size(t));
            else
                x = eval(xFunction);
            end
            
            if isConstantY
                y = str2double(yFunction) * ones(size(t));
            else
                y = eval(yFunction);
            end
            
            if isConstantZ
                z = str2double(zFunction) * ones(size(t));
            else
                z = eval(zFunction);
            end
            
            % Filter the points based on the range limits
            xlimStr = app.XlimminmaxEditField.Value;
            ylimStr = app.YlimminmaxEditField.Value;
            zlimMax = app.ZlimmaxEditField.Value;
            
            xlimValues = str2double(strsplit(xlimStr, ','));
            ylimValues = str2double(strsplit(ylimStr, ','));
            zlimValues = [0, str2double(zlimMax)];  % Limit z to positive values
            
            filteredIndices = x >= xlimValues(1) & x <= xlimValues(2) & ...
                              y >= ylimValues(1) & y <= ylimValues(2) & ...
                              z >= zlimValues(1) & z <= zlimValues(2);
            
            filteredX = x(filteredIndices);
            filteredY = y(filteredIndices);
            filteredZ = z(filteredIndices);
            filteredYaw = zeros(size(filteredX)); %glitch to avoid yaw for the moment
            
            % Add the points to the mission plan
            app.missionPlanX = [app.missionPlanX, filteredX];
            app.missionPlanY = [app.missionPlanY, filteredY];
            app.missionPlanZ = [app.missionPlanZ, filteredZ];
            app.missionPlanYaw = [app.missionPlanYaw, filteredYaw]; %glitch to avoid yaw for the moment
            
            % Clear the UIAxes before plotting
            cla(app.UIAxes);
            
            % Plot the complete mission plan
            plot3(app.UIAxes, app.missionPlanX, app.missionPlanY, app.missionPlanZ, 'ro-');
            hold(app.UIAxes, 'on');
            plot3(app.UIAxes, filteredX, filteredY, filteredZ, 'bo-');
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            xlim(app.UIAxes, xlimValues);
            ylim(app.UIAxes, ylimValues);
            zlim(app.UIAxes, zlimValues);
            view(app.UIAxes, 3);
            
            % Save filtered data to workspace for use in Simulink
            assignin('base', 'missionPlanX', app.missionPlanX);
            assignin('base', 'missionPlanY', app.missionPlanY);
            assignin('base', 'missionPlanZ', app.missionPlanZ);
            assignin('base', 'missionPlanYaw', app.missionPlanYaw);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
        % Reset the mission plan and clear the stored data points
        app.missionPlanX = [];
        app.missionPlanY = [];
        app.missionPlanZ = [];
        app.missionPlanYaw = [];
        assignin('base', 'missionPlanX', app.missionPlanX);
        assignin('base', 'missionPlanY', app.missionPlanY);
        assignin('base', 'missionPlanZ', app.missionPlanZ);
        assignin('base', 'missionPlanYaw', app.missionPlanYaw);
        
        % Clear the graph
        cla(app.UIAxes);
        
        % Reset the X, Y, and Z limits of the UIAxes
        xlim(app.UIAxes, 'auto');
        ylim(app.UIAxes, 'auto');
        zlim(app.UIAxes, 'auto');
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            assignin('base', 'timeConstant', app.tEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.CLim = [0 1];
            app.XpointEditFieldLabel = uilabel(app.UIFigure);
            app.XpointEditFieldLabel.Text = 'X point';
            app.XpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add Point';
            app.YpointEditFieldLabel = uilabel(app.UIFigure);
            app.YpointEditFieldLabel.Text = 'Y point';
            app.YpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.YpointEditField.LowerLimitInclusive = 'off';
            app.YpointEditField.UpperLimitInclusive = 'off';
            app.ZpointEditFieldLabel = uilabel(app.UIFigure);
            app.ZpointEditFieldLabel.Text = 'Z point';
            app.ZpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawAngledegEditFieldLabel = uilabel(app.UIFigure);
            app.YawAngledegEditFieldLabel.Text = 'Yaw Angle (deg)';
            app.YawAngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.PlotTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryButtonPushed, true);
            app.PlotTrajectoryButton.Text = 'Plot Trajectory';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Points', 'Function'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Points';
            app.SelectYourTrajectoryModeLabel = uilabel(app.UIFigure);
            app.SelectYourTrajectoryModeLabel.Text = 'Select Your Trajectory Mode:';
            app.VehicleTrajectoryLabel = uilabel(app.UIFigure);
            app.VehicleTrajectoryLabel.Text = 'Vehicle Trajectory';
            app.XtFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.XtFunctionEditFieldLabel.Text = 'X(t) Function';
            app.XtFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.YtFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.YtFunctionEditFieldLabel.Text = 'Y(t) Function';
            app.YtFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.ZtFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.ZtFunctionEditFieldLabel.Text = 'Z(t) Function';
            app.ZtFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.TimegivenbetweentwopositionpointsLabel = uilabel(app.UIFigure);
            app.TimegivenbetweentwopositionpointsLabel.Text = 'Time given between two position points';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.proportionaltodistanceinmetersLabel = uilabel(app.UIFigure);
            app.proportionaltodistanceinmetersLabel.Text = '(proportional to distance in meters)';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.XlimminmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XlimminmaxEditFieldLabel.Text = 'X lim (min,max)';
            app.XlimminmaxEditField = uieditfield(app.UIFigure, 'text');
            app.YlimminmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YlimminmaxEditFieldLabel.Text = 'Y lim (min,max)';
            app.YlimminmaxEditField = uieditfield(app.UIFigure, 'text');
            app.ZlimmaxEditFieldLabel = uilabel(app.UIFigure);
            app.ZlimmaxEditFieldLabel.Text = 'Z lim (max)';
            app.ZlimmaxEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MissionPlanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end